classdef ea3hw1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SlidingBoxTab_2                 matlab.ui.container.Tab
        RunSimulationButton             matlab.ui.control.Button
        EditField                       matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        StopsimulationwhenDropDown      matlab.ui.control.DropDown
        StopsimulationwhenDropDownLabel  matlab.ui.control.Label
        RampangledegreesEditField       matlab.ui.control.NumericEditField
        RampangledegreesEditFieldLabel  matlab.ui.control.Label
        MasskgEditField                 matlab.ui.control.NumericEditField
        MasskgEditFieldLabel            matlab.ui.control.Label
        CoefficientoffrictionEditField  matlab.ui.control.NumericEditField
        CoefficientoffrictionEditFieldLabel  matlab.ui.control.Label
        xaxisDropDown                   matlab.ui.control.DropDown
        xaxisDropDownLabel              matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        CollisionTab                    matlab.ui.container.Tab
    end

    
    methods (Access = private)
        
    function RunButtonPushed(app, event)

    % ----------------------------------
    % Read user inputs
    % ----------------------------------
    mu = app.FrictionEditField.Value;
    m = app.MassEditField.Value;
    thetaDeg = app.RampAngleEditField.Value;

    s0 = app.InitialPositionEditField.Value;
    v0 = app.InitialVelocityEditField.Value;

    stopType = app.StopConditionDropDown.Value;
    stopValue = app.StopValueEditField.Value;

    coordinateSystem = app.XaxisDropDown.Value;

    % ----------------------------------
    % Input checking
    % ----------------------------------
    if m <= 0
        uialert(app.UIFigure, ...
            'Mass must be greater than zero.', ...
            'Invalid Input');
        return
    end

    if mu < 0
        uialert(app.UIFigure, ...
            'Coefficient of friction cannot be negative.', ...
            'Invalid Input');
        return
    end

    if thetaDeg < 0 || thetaDeg >= 90
        uialert(app.UIFigure, ...
            'Ramp angle must be between 0 and 90 degrees.', ...
            'Invalid Input');
        return
    end

    % ----------------------------------
    % Constants
    % ----------------------------------
    g = 9.81;
    theta = deg2rad(thetaDeg);

    dt = 0.01;

    % Safety maximum so simulation cannot run forever
    maxTime = 30;
    maxSteps = round(maxTime/dt) + 1;

    % ----------------------------------
    % Preallocate arrays
    % ----------------------------------
    t = zeros(maxSteps,1);
    s = zeros(maxSteps,1);
    v = zeros(maxSteps,1);
    a = zeros(maxSteps,1);

    KE = zeros(maxSteps,1);
    PE = zeros(maxSteps,1);
    frictionEnergy = zeros(maxSteps,1);

    % Initial conditions
    s(1) = s0;
    v(1) = v0;

    KE(1) = 0.5*m*v0^2;
    PE(1) = m*g*s0*sin(theta);

    finalIndex = maxSteps;

    % ----------------------------------
    % Numerical simulation
    % ----------------------------------
    for k = 1:maxSteps-1

        % ----------------------------------
        % Find acceleration
        % Positive direction = UP the ramp
        % ----------------------------------

        if abs(v(k)) < 1e-8

            % Box is essentially stopped.
            % Determine whether gravity can overcome friction.

            if sin(theta) <= mu*cos(theta)

                % Friction can hold the box stationary
                ak = 0;

            else

                % Box slides DOWN the ramp
                ak = -g*(sin(theta) - mu*cos(theta));

            end

        else

            % Friction always opposes motion
            ak = -g*sin(theta) ...
               - mu*g*cos(theta)*sign(v(k));

        end

        a(k) = ak;

        % ----------------------------------
        % Advance one time step
        % ----------------------------------
        vNew = v(k) + ak*dt;

        sNew = s(k) + v(k)*dt + 0.5*ak*dt^2;

        % If friction causes an object moving UP
        % the ramp to stop, prevent numerical
        % overshoot from immediately reversing it.
        if v(k) > 0 && vNew < 0

            % Approximate stopping location
            if abs(ak) > 1e-12
                timeToStop = -v(k)/ak;

                sNew = s(k) + ...
                    v(k)*timeToStop + ...
                    0.5*ak*timeToStop^2;
            end

            vNew = 0;

        end

        % Store next state
        t(k+1) = t(k) + dt;
        s(k+1) = sNew;
        v(k+1) = vNew;

        % ----------------------------------
        % Energy calculations
        % ----------------------------------

        KE(k+1) = 0.5*m*vNew^2;

        % Height relative to s = 0
        PE(k+1) = m*g*sNew*sin(theta);

        % Distance traveled during this step
        ds = abs(sNew - s(k));

        % Thermal/dissipated energy from friction
        frictionEnergy(k+1) = ...
            frictionEnergy(k) + ...
            mu*m*g*cos(theta)*ds;

        % ----------------------------------
        % Check user's stopping condition
        % ----------------------------------

        shouldStop = false;

        if strcmpi(stopType,'position')

            % Detect crossing of requested position
            oldDifference = s(k) - stopValue;
            newDifference = sNew - stopValue;

            if k > 1 && ...
               oldDifference*newDifference <= 0

                shouldStop = true;
            end

        elseif strcmpi(stopType,'velocity')

            oldDifference = v(k) - stopValue;
            newDifference = vNew - stopValue;

            if k > 1 && ...
               oldDifference*newDifference <= 0

                shouldStop = true;
            end

        end

        if shouldStop

            finalIndex = k+1;
            break

        end

    end

    % ----------------------------------
    % Trim unused array entries
    % ----------------------------------
    t = t(1:finalIndex);
    s = s(1:finalIndex);
    v = v(1:finalIndex);

    KE = KE(1:finalIndex);
    PE = PE(1:finalIndex);
    frictionEnergy = frictionEnergy(1:finalIndex);

    % ----------------------------------
    % Coordinate system selection
    % ----------------------------------

    if strcmpi(coordinateSystem,'horizontal')

        positionPlot = s*cos(theta);
        positionLabel = 'Horizontal Position (m)';

    else

        positionPlot = s;
        positionLabel = 'Position Along Ramp (m)';

    end

    % Speed is magnitude of velocity
    speed = abs(v);

    % ----------------------------------
    % Energy conservation check
    % ----------------------------------

    mechanicalEnergy = KE + PE;

    energyCheck = ...
        mechanicalEnergy + frictionEnergy;

    % ----------------------------------
    % Position vs Time
    % ----------------------------------

    cla(app.PositionAxes)

    plot(app.PositionAxes, ...
        t, positionPlot, ...
        'LineWidth',2)

    title(app.PositionAxes,'Position vs Time')
    xlabel(app.PositionAxes,'Time (s)')
    ylabel(app.PositionAxes,positionLabel)

    grid(app.PositionAxes,'on')

    % ----------------------------------
    % Speed vs Time
    % ----------------------------------

    cla(app.SpeedAxes)

    plot(app.SpeedAxes, ...
        t, speed, ...
        'LineWidth',2)

    title(app.SpeedAxes,'Speed vs Time')
    xlabel(app.SpeedAxes,'Time (s)')
    ylabel(app.SpeedAxes,'Speed (m/s)')

    grid(app.SpeedAxes,'on')

    % ----------------------------------
    % Energy vs Time
    % ----------------------------------

    cla(app.EnergyAxes)

    plot(app.EnergyAxes,t,KE, ...
        'LineWidth',1.5)

    hold(app.EnergyAxes,'on')

    plot(app.EnergyAxes,t,PE, ...
        'LineWidth',1.5)

    plot(app.EnergyAxes,t,frictionEnergy, ...
        'LineWidth',1.5)

    plot(app.EnergyAxes,t,energyCheck, ...
        '--','LineWidth',2)

    hold(app.EnergyAxes,'off')

    title(app.EnergyAxes,'Energy vs Time')
    xlabel(app.EnergyAxes,'Time (s)')
    ylabel(app.EnergyAxes,'Energy (J)')

    legend(app.EnergyAxes, ...
        'Kinetic Energy', ...
        'Potential Energy', ...
        'Energy Lost to Friction', ...
        'Total Energy Check', ...
        'Location','best')

    grid(app.EnergyAxes,'on')
        end
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SlidingBoxTab_2 = uitab(app.TabGroup);
            app.SlidingBoxTab_2.Title = 'Sliding Box';
            app.UIAxes3 = uiaxes(app.SlidingBoxTab_2);
            title(app.UIAxes3, 'Energy vs Time ')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Energy')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.SlidingBoxTab_2);
            title(app.UIAxes2, 'Speed vs Time ')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Speed')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.SlidingBoxTab_2);
            title(app.UIAxes, 'Position vs Time ')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Position')
            zlabel(app.UIAxes, 'Z')
            app.xaxisDropDownLabel = uilabel(app.SlidingBoxTab_2);
            app.xaxisDropDownLabel.Text = 'x-axis ';
            app.xaxisDropDown = uidropdown(app.SlidingBoxTab_2);
            app.xaxisDropDown.Items = {'along the ground ', 'along the ramp', ''};
            app.xaxisDropDown.Value = 'along the ground ';
            app.CoefficientoffrictionEditFieldLabel = uilabel(app.SlidingBoxTab_2);
            app.CoefficientoffrictionEditFieldLabel.Text = 'Coefficient of friction';
            app.CoefficientoffrictionEditField = uieditfield(app.SlidingBoxTab_2, 'numeric');
            app.MasskgEditFieldLabel = uilabel(app.SlidingBoxTab_2);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MasskgEditField = uieditfield(app.SlidingBoxTab_2, 'numeric');
            app.RampangledegreesEditFieldLabel = uilabel(app.SlidingBoxTab_2);
            app.RampangledegreesEditFieldLabel.Text = 'Ramp angle (degrees)';
            app.RampangledegreesEditField = uieditfield(app.SlidingBoxTab_2, 'numeric');
            app.StopsimulationwhenDropDownLabel = uilabel(app.SlidingBoxTab_2);
            app.StopsimulationwhenDropDownLabel.Text = 'Stop simulation when';
            app.StopsimulationwhenDropDown = uidropdown(app.SlidingBoxTab_2);
            app.StopsimulationwhenDropDown.Items = {'position', 'velocity'};
            app.StopsimulationwhenDropDown.Value = 'position';
            app.Label = uilabel(app.SlidingBoxTab_2);
            app.Label.Text = '=';
            app.EditField = uieditfield(app.SlidingBoxTab_2, 'numeric');
            app.RunSimulationButton = uibutton(app.SlidingBoxTab_2, 'push');
            app.RunSimulationButton.Text = 'Run Simulation';
            app.CollisionTab = uitab(app.TabGroup);
            app.CollisionTab.Title = 'Collision';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea3hw1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end